classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        AbrirvideoButton                matlab.ui.control.Button
        FramesSliderLabel               matlab.ui.control.Label
        FramesSlider                    matlab.ui.control.Slider
        UIAxes_2                        matlab.ui.control.UIAxes
        IntegracindeVideoPanel          matlab.ui.container.Panel
        PromediarvideoButton            matlab.ui.control.Button
        FrameinicialDropDownLabel       matlab.ui.control.Label
        FrameinicialDropDown            matlab.ui.control.DropDown
        FramefinalDropDownLabel         matlab.ui.control.Label
        FramefinalDropDown              matlab.ui.control.DropDown
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        MincolorlevelSliderLabel        matlab.ui.control.Label
        MincolorlevelSlider             matlab.ui.control.Slider
        MaxcolorlevelSliderLabel        matlab.ui.control.Label
        MaxcolorlevelSlider             matlab.ui.control.Slider
        FiltrarimagenPanel              matlab.ui.container.Panel
        FiltrarButton                   matlab.ui.control.Button
        RtophatDropDownLabel            matlab.ui.control.Label
        RtophatDropDown                 matlab.ui.control.DropDown
        MapadeestrellasPanel            matlab.ui.container.Panel
        GenerarButton                   matlab.ui.control.Button
        AlfaSliderLabel                 matlab.ui.control.Label
        AlfaSlider                      matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        RSlider                         matlab.ui.control.Slider
        nDropDownLabel                  matlab.ui.control.Label
        nDropDown                       matlab.ui.control.DropDown
        IdentificarButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AstrometraTab                   matlab.ui.container.Tab
        MarcarestrellasButton           matlab.ui.control.Button
        CalibrarButton                  matlab.ui.control.Button
        VerCalibracionButton            matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        MeanOCLabel                     matlab.ui.control.Label
        ResolLabel                      matlab.ui.control.Label
        estrellasLabel                  matlab.ui.control.Label
        MeanOCzLabel                    matlab.ui.control.Label
        MeanOCAzLabel                   matlab.ui.control.Label
        FotometraTab                    matlab.ui.container.Tab
        ResultadosPanel_2               matlab.ui.container.Panel
        kLabel                          matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        NestrellasLabel                 matlab.ui.control.Label
        MeanOCLabel_2                   matlab.ui.control.Label
        p_3Label                        matlab.ui.control.Label
        p_2Label                        matlab.ui.control.Label
        p_1Label                        matlab.ui.control.Label
        RbackgroundDropDownLabel        matlab.ui.control.Label
        RbackgroundDropDown             matlab.ui.control.DropDown
        RstarDropDownLabel              matlab.ui.control.Label
        RstarDropDown                   matlab.ui.control.DropDown
        CalibrarfotomButton             matlab.ui.control.Button
        BlidoTab                        matlab.ui.container.Tab
        CentroideButton                 matlab.ui.control.Button
        GaussianaButton                 matlab.ui.control.Button
        AstromyfotomButton              matlab.ui.control.Button
        LcuadradopixEditFieldLabel      matlab.ui.control.Label
        LcuadradopixEditField           matlab.ui.control.NumericEditField
        VisualizarGaussianaButtonGroup  matlab.ui.container.ButtonGroup
        ContourButton                   matlab.ui.control.RadioButton
        SurfButton                      matlab.ui.control.RadioButton
        UmbralmagnitudEditFieldLabel    matlab.ui.control.Label
        UmbralmagnitudEditField         matlab.ui.control.NumericEditField
        DeteccinTab                     matlab.ui.container.Tab
        EvaluarAlgoritmoDetButton       matlab.ui.control.Button
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        UmbraldiferenciaEditFieldLabel  matlab.ui.control.Label
        UmbraldiferenciaEditField       matlab.ui.control.NumericEditField
        pixsobreumbraldifEditFieldLabel  matlab.ui.control.Label
        pixsobreumbraldifEditField      matlab.ui.control.NumericEditField
        MaxdistmedianaacentroideEditFieldLabel  matlab.ui.control.Label
        MaxdistmedianaacentroideEditField  matlab.ui.control.NumericEditField
        AbrirmetadatosButton            matlab.ui.control.Button
        CrearestacinPanel               matlab.ui.container.Panel
        LATEditFieldLabel               matlab.ui.control.Label
        LATEditField                    matlab.ui.control.NumericEditField
        LONEditFieldLabel               matlab.ui.control.Label
        LONEditField                    matlab.ui.control.NumericEditField
        AliasEditFieldLabel             matlab.ui.control.Label
        AliasEditField                  matlab.ui.control.EditField
        AgregarButton                   matlab.ui.control.Button
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        VermscaraButton                 matlab.ui.control.Button
        UTEditFieldLabel                matlab.ui.control.Label
        UTEditField                     matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: AbrirvideoButton
        function AbrirvideoButtonPushed(app, event)
            global vidObj file
            
            [file,path]=uigetfile('*.avi','Abrir archivo de video...');
            if file~=0
                try
                    vidObj = VideoReader(strcat(path,file));
                    
                    duracion=vidObj.Duration;
                    framerate=vidObj.FrameRate;
                    
                    vidFrame = readFrame(vidObj);
                    imagesc(app.UIAxes,vidFrame(:,:,1));
                    grid(app.UIAxes,'on');
                    app.FramesSlider.Limits = [1 round(duracion*framerate)];
                    app.FramesSlider.Enable = 'on';
                    
                    listaFrames=cell(framerate*duracion,1);
                    for i=1:framerate*duracion
                        listaFrames(i)={strcat('Frame ',num2str(i,'%d'))};
                    end
                    app.FrameinicialDropDown.ItemsData = 1:framerate*duracion;
                    app.FrameinicialDropDown.Items=listaFrames;
                    
                    app.FramefinalDropDown.ItemsData = 1:framerate*duracion;
                    app.FramefinalDropDown.Items=listaFrames;
                    app.CalibrarButton.Enable='on';
                    app.VerCalibracionButton.Enable='on';
                    
                    app.PromediarvideoButton.Enable='on';
                    app.EvaluarAlgoritmoDetButton.Enable='on';
                    app.CentroideButton.Enable='on';
                    
                    app.FiltrarButton.Enable='off';
                    app.IdentificarButton.Enable='off';
                    app.GenerarButton.Enable='off';
                    app.MarcarestrellasButton.Enable='off';
                    
                    app.VermscaraButton.Enable='on';
                    app.Label_3.Text=file(1:end-4);
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            global file starsCat starNames
            if file~=0
                
                try
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    nStars=str2double(app.nDropDown.Value);
                    
                    [~,names,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    R=app.RSlider.Value;
                    alfa=app.AlfaSlider.Value;
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        
                        if El>0
                            count=count+1;
                            try
                                p=load(strcat('Station ',num2str(stationId),'/constantesPlaca.txt'));
                                [iP,jP] = hor2ijCaldasTancredi(pi/2-deg2rad(El),deg2rad(Az),p);
                            catch err
                                r = R*((90-El)/90);
                                iP=m/2-r*cosd(Az+alfa);
                                jP=n/2-r*sind(Az+alfa);
                            end
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            starNames{count}=names{count2};
                            plot(app.UIAxes_2,jP,iP,'*');
                            text(app.UIAxes_2,jP,iP,starNames{count});
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                    app.MarcarestrellasButton.Enable='on';
                catch err
                    msgbox(err.message);
                end
            else
                msgbox('Debe abrir imagen o video para integrar');
            end
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            global filtrada promediada
            try
                A=promediada;
                se = strel('disk',str2double(app.RtophatDropDown.Value));
                filtrada = imtophat(A,se);
                app.MincolorlevelSlider.Limits=[0 double(max(max(filtrada)))-2];
                app.MaxcolorlevelSlider.Limits=[0 double(max(max(filtrada)))];
                
                imagesc(app.UIAxes,filtrada);
                caxis(app.UIAxes,[0 double(max(max(filtrada)))]);
                grid(app.UIAxes,'on');
                
                app.IdentificarButton.Enable='on';
                app.GenerarButton.Enable='on';
            catch err
                msgbox(err.message);
            end
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            global vidObj
            v=event.Value;
            vidObj.CurrentTime=((v-1)/vidObj.FrameRate);
            try
                vidFrame = readFrame(vidObj);
                imagesc(app.UIAxes,vidFrame(:,:,1));
                grid(app.UIAxes,'on');
            catch err
                msgbox(err.message);
            end
            set(app.Label,'Text',num2str(round(v),'%d'));
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            global vidObj
            v=event.Value;
            vidObj.CurrentTime=((v-1)/vidObj.FrameRate);
            try
                vidFrame = readFrame(vidObj);
                imagesc(app.UIAxes,vidFrame(:,:,1));
                grid(app.UIAxes,'on');
            catch err
                msgbox(err.message);
            end
            set(app.Label,'Text',num2str(round(v),'%d'));
        end

        % Callback function
        function ImportarintegradaButtonPushed(app, event)
            global integrada file
            
            [file,path]=uigetfile('*.mat','Abrir integrada...');
            if (file~=0)
                try
                    h=load(strcat(path,file));
                    integrada=h.integrada;
                    
                    try
                        stationId = getStationID(file);
                        BW=load(strcat('Station ',num2str(stationId),'/Mask.mat'));
                    catch err
                        BW=load('Mask.mat');
                    end
                    
                    integrada=integrada.*uint32(BW.BW);
                    
                    app.MincolorlevelSlider.Enable='on';
                    app.MincolorlevelSlider.Limits=[0 double(max(max(integrada)))-2];
                    app.MincolorlevelSlider.Value=0;
                    app.MaxcolorlevelSlider.Enable='on';
                    app.MaxcolorlevelSlider.Limits=[0 double(max(max(integrada)))];
                    app.MaxcolorlevelSlider.Value=double(max(max(integrada)));
                    
                    imagesc(app.UIAxes,integrada);
                    caxis(app.UIAxes,[0 double(max(max(integrada)))]);
                    grid(app.UIAxes,'on');
                    app.FiltrarButton.Enable='on';
                    
                catch err
                    msgbox(err.message);
                end
                
            end
        end

        % Button pushed function: PromediarvideoButton
        function PromediarvideoButtonPushed(app, event)
            global vidObj file promediada
            try
                resolucion=[480 640];
                
                %Analyze and clean video stream
                count=1;
                count2=0;
                anterior=uint8(zeros(480,640));
                promediada=uint32(zeros(480,640));
                f0 = app.FrameinicialDropDown.Value;
                ff = app.FramefinalDropDown.Value;
                
                vidObj.CurrentTime=(f0-1)/vidObj.FrameRate;
                app.StatusLamp.Color='red';
                app.FramesSlider.Enable='off';
                app.AbrirvideoButton.Enable='off';
                app.FrameinicialDropDown.Enable='off';
                app.FramefinalDropDown.Enable='off';
                app.PromediarvideoButton.Enable='off';
                
                while count2<ff-f0+1 && hasFrame(vidObj)
                    este = readFrame(vidObj);
                    if vidObj.Height ~= 480
                        este=imresize(este(:,:,1),resolucion);
                    else
                        este=este(:,:,1);
                    end
                    dif = imabsdiff(este,anterior);
                    if any(dif(:))
                        promediada=promediada+uint32(este);
                        count=count+1;
                    else
                    end
                    count2=count2+1;
                    anterior=este;
                end
                app.StatusLamp.Color='green';
                app.FramesSlider.Enable='on';
                app.AbrirvideoButton.Enable='on';
                app.FrameinicialDropDown.Enable='on';
                app.FramefinalDropDown.Enable='on';
                app.PromediarvideoButton.Enable='on';
                app.MincolorlevelSlider.Enable='on';
                app.MaxcolorlevelSlider.Enable='on';
                
                try
                    stationId = getStationID(file);
                    BW=load(strcat('Station ',num2str(stationId),'/Mask.mat'));
                    promediada=promediada.*uint32(BW.BW);
                catch err
                end
                
                promediada=promediada./count;
                app.MincolorlevelSlider.Enable='on';
                app.MincolorlevelSlider.Limits=[0 double(max(max(promediada)))-2];
                app.MincolorlevelSlider.Value=0;
                app.MaxcolorlevelSlider.Enable='on';
                app.MaxcolorlevelSlider.Limits=[0 double(max(max(promediada)))];
                app.MaxcolorlevelSlider.Value=double(max(max(promediada)));
                
                imagesc(app.UIAxes,promediada);
                caxis(app.UIAxes,[0 double(max(max(promediada)))]);
                grid(app.UIAxes,'on');
                app.FiltrarButton.Enable='on';
            catch err
                msgbox(err.message);
            end
        end

        % Value changed function: MaxcolorlevelSlider
        function MaxcolorlevelSliderValueChanged(app, event)
            valuem = app.MincolorlevelSlider.Value;
            valueM = app.MaxcolorlevelSlider.Value;
            caxis(app.UIAxes,[valuem valueM]);
            caxis(app.UIAxes_2,[valuem valueM]);
        end

        % Value changing function: MaxcolorlevelSlider
        function MaxcolorlevelSliderValueChanging(app, event)
            valuem = app.MincolorlevelSlider.Value;
            valueM = event.Value;
            caxis(app.UIAxes,[valuem valueM]);
        end

        % Value changed function: MincolorlevelSlider
        function MincolorlevelSliderValueChanged(app, event)
            valuem = app.MincolorlevelSlider.Value;
            app.MaxcolorlevelSlider.Limits=[valuem+1 app.MaxcolorlevelSlider.Limits(2)];
            valueM = app.MaxcolorlevelSlider.Value;
            caxis(app.UIAxes,[valuem valueM]);
            caxis(app.UIAxes_2,[valuem valueM]);
        end

        % Value changing function: MincolorlevelSlider
        function MincolorlevelSliderValueChanging(app, event)
            valuem = event.Value;
            app.MaxcolorlevelSlider.Limits=[valuem+1 app.MaxcolorlevelSlider.Limits(2)];
            valueM = app.MaxcolorlevelSlider.Value;
            caxis(app.UIAxes,[valuem valueM]);
            caxis(app.UIAxes_2,[valuem valueM]);
        end

        % Value changing function: AlfaSlider
        function AlfaSliderValueChanging(app, event)
            global file starsCat
            
            alfa = event.Value;
            R=app.RSlider.Value;
            nStars=str2double(app.nDropDown.Value);
            
            if file~=0
                
                try
                    
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        if El>0
                            count=count+1;
                            r = R*((90-El)/90);
                            iP=m/2-r*cosd(Az+alfa);
                            jP=n/2-r*sind(Az+alfa);
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            plot(app.UIAxes_2,jP,iP,'*');
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                    app.MarcarestrellasButton.Enable='on';
                catch err
                    msgbox(err.message);
                end
            end
            
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            global file starsCat
            R = event.Value;
            alfa=app.AlfaSlider.Value;
            nStars=str2double(app.nDropDown.Value);
            
            if file~=0
                
                try
                    
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        if El>0
                            count=count+1;
                            r = R*((90-El)/90);
                            iP=m/2-r*cosd(Az+alfa);
                            jP=n/2-r*sind(Az+alfa);
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            plot(app.UIAxes_2,jP,iP,'*');
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                    app.MarcarestrellasButton.Enable='on';
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Callback function
        function MarcarestrellasButtonValueChanged(app, event)
            global filtrada starsCat file
            
            if ~isempty(filtrada)
                figure;
                valueM = app.MaxcolorlevelSlider.Value;
                valuem = app.MincolorlevelSlider.Value;
                imagesc(filtrada,[valuem valueM]);
                hold on
                plot(starsCat(:,7),starsCat(:,6),'*k');
                
                [j,i]=ginput();
                if ~isempty(i)
                    iSelect=(i);
                    jSelect=(j);
                    lado=3;
                    for k=1:length(j)
                        cuadra=filtrada(round(i(k))-lado:round(i(k))+lado,round(j(k))-lado:round(j(k))+lado);
                        [ci,cj]=centroide(cuadra);
                        jSelect(k)=round(cj+j(k)-lado);
                        iSelect(k)=round(ci+i(k)-lado);
                    end
                    hold on
                    plot(j,i,'.m');
                    answer = questdlg('Confirma selección?', 'Sí', 'No');
                    if strcmp(answer,'Sí')
                        stationId = getStationID(file);
                        fid = fopen(strcat('Station ',num2str(stationId),'/matcheadas.txt'),'a+');
                        
                        for i=1:length(iSelect)
                            d=sqrt((iSelect(i)-starsCat(:,6)).^2+(jSelect(i)-starsCat(:,7)).^2);
                            [~,k]=min(d);
                            fprintf(fid,'%.0f %.0f %.3f %.3f %.0f %.0f\n',[iSelect(i) jSelect(i) starsCat(k,4) starsCat(k,5) starsCat(k,6) starsCat(k,7)]);
                        end
                        
                        fclose(fid);
                    end
                end
            end
        end

        % Callback function
        function CalibrarButtonValueChanged(app, event)
            value = app.CalibrarButton.Value;
            
        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
            global XYZA2 p file
            try
                stationId = getStationID(file);
                matcheados= load(strcat('Station ',num2str(stationId),'/matcheadas.txt'));
                
                R = app.RSlider.Value;
                alfa=app.AlfaSlider.Value;
                XYZA = [matcheados(:,1) matcheados(:,2) (pi/180)*(90-matcheados(:,4)) (pi/180)*(matcheados(:,3))];
                
                if R==0
                    R=240;
                end
                
                p0 = [0.5 0.5 0 pi/(2*R) 0 0 deg2rad(alfa) 1];
                
                [p,zOut,Azout,XYZA2,res] = astrometria(p0,XYZA);
                
                save(strcat('Station',num2str(stationId),'/constantesPlaca.txt'),'p','-ascii');
                
                resolucion = (180/pi)*60*(240*p(4)+p(5)*240^2+p(6)*240^3)/240;
                app.MeanOCLabel.Text=strcat('Mean O-C: ',num2str(res(1),'%.0f'),' arcmin');
                app.ResolLabel.Text=strcat('Resol.: ',num2str(resolucion,'%.0f'),' amin/pix');
                app.estrellasLabel.Text=strcat('# stars: ',num2str(length(zOut),'%.0f'));
                
                app.MeanOCzLabel.Text=strcat('Mean O-C,z: ',num2str(res(2),'%.0f'),' arcmin');
                app.MeanOCAzLabel.Text=strcat('Mean O-C,Az: ',num2str(res(3),'%.0f'),' arcmin');
                
                figure
                plot(rad2deg(zOut),rad2deg(XYZA2(:,3)),'*');
                xlabel('z calculated [deg]');ylabel('z observed [deg]');
                title('Observed vs calculated zenith angle');
                figure
                plot(rad2deg(Azout),rad2deg(XYZA2(:,4)),'*');
                xlabel('Az calculated [deg]');ylabel('Az observed [deg]');
                title('Observed vs calculated azimuth angle');
            catch err
                msgbox(err.message);
            end
            
        end

        % Button pushed function: VerCalibracionButton
        function VerCalibracionButtonPushed(app, event)
            global file
            try
                stationId = getStationID(file);
                p=load(strcat('Station ',num2str(stationId),'/constantesPlaca.txt'));
                
                mapaZ=zeros(480,640);
                mapaAZ=zeros(480,640);
                
                for i=1:480
                    for j=1:640
                        [z,Az] = ij2horCaldasTancredi(i,j,p);
                        mapaZ(i,j)=z*180/pi;
                        mapaAZ(i,j)=Az*180/pi;
                    end
                end
                
                figure;
                imagesc(mapaZ);
                title('Zenith angle map');
                axis ij;
                figure;
                imagesc(mapaAZ);
                title('Azimuth angle map');
                axis ij;
                
                try
                    stationId = getStationID(file);
                    A=load(strcat('Station ',num2str(stationId),'/Mask.mat')');
                catch err
                    A=load('Mask.mat');
                end
                BW1 = edge(A.BW,'prewitt');
                [i,j] = find(BW1==1);
                [zHorizonte,AzHorizonte] = ij2horCaldasTancredi(i,j,p);
                figure
                B = sortrows([zHorizonte,AzHorizonte],2);
                plot(B(:,2)*(180/pi),90-B(:,1)*(180/pi),'*c');
                axis([0 360 0 90]);
                legend(strcat('Mean FOV:',num2str(mean(zHorizonte*2*(180/pi)),'%.0f')));
                xlabel('Azimuth [deg]');ylabel('Elevation angle [deg]');
                title('Masked Horizon');
                
                BW1(BW1==1)=255;
                pC=puntosCardinales(mapaZ,mapaAZ);
                
                figure;imshow(BW1,'Colormap', [0 0 0;1 0 0;0 1 1])
                hold on
                plot([pC(1,2) pC(3,2)],[pC(1,1) pC(3,1)],'r');
                plot([pC(2,2) pC(4,2)],[pC(2,1) pC(4,1)],'b');
                plot(p(3),p(2),'*');
                plot(320,240,'*y');
                text(pC(1,2),pC(1,1),'N','Color','red','FontSize',14);
                text(pC(2,2),pC(2,1),'E','Color','blue','FontSize',14);
                text(pC(3,2),pC(3,1),'S','Color','red','FontSize',14);
                text(pC(4,2),pC(4,1),'O','Color','blue','FontSize',14);
            catch err
                msgbox(err.message);
            end
        end

        % Button pushed function: MarcarestrellasButton
        function MarcarestrellasButtonPushed(app, event)
            global filtrada starsCat file starNames
            
            if ~isempty(filtrada)
                figure;
                valueM = app.MaxcolorlevelSlider.Value;
                valuem = app.MincolorlevelSlider.Value;
                
                if valuem>=valueM
                    if valuem==0
                        valueM=10;
                    else
                        valueM=valuem*1.10;
                    end
                end
                imagesc(filtrada,[valuem valueM]);
                hold on
                plot(starsCat(:,7),starsCat(:,6),'*k');
                for i=1:length(starsCat(:,7))
                    text(starsCat(i,7),starsCat(i,6),starNames{i});
                end
                try
                    [j,i]=ginput();
                    if ~isempty(i)
                        iSelect=(i);
                        jSelect=(j);
                        lado=3;
                        for k=1:length(j)
                            cuadra=filtrada(round(i(k))-lado:round(i(k))+lado,round(j(k))-lado:round(j(k))+lado);
                            [ci,cj]=centroide(cuadra);
                            jSelect(k)=round(cj+j(k)-lado);
                            iSelect(k)=round(ci+i(k)-lado);
                        end
                        hold on
                        plot(j,i,'.m');
                        answer = questdlg('Confirma selección?', 'Sí', 'No');
                        if strcmp(answer,'Yes')
                            stationId = getStationID(file);
                            
                            fid = fopen(strcat('Station ',num2str(stationId),'/matcheadas.txt'),'a+');
                            
                            for i=1:length(iSelect)
                                d=sqrt((iSelect(i)-starsCat(:,6)).^2+(jSelect(i)-starsCat(:,7)).^2);
                                [~,k]=min(d);
                                fprintf(fid,'%.0f %.0f %.3f %.3f %.0f %.0f\n',[iSelect(i) jSelect(i) starsCat(k,4) starsCat(k,5) starsCat(k,6) starsCat(k,7)]);
                            end
                            
                            fclose(fid);
                        end
                    end
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Value changed function: AlfaSlider
        function AlfaSliderValueChanged(app, event)
            global file starsCat
            alfa = app.AlfaSlider.Value;
            R=app.RSlider.Value;
            nStars=str2double(app.nDropDown.Value);
            
            if file~=0
                
                try
                    
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        if El>0
                            count=count+1;
                            r = R*((90-El)/90);
                            iP=m/2-r*cosd(Az+alfa);
                            jP=n/2-r*sind(Az+alfa);
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            plot(app.UIAxes_2,jP,iP,'*');
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                    app.MarcarestrellasButton.Enable='on';
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            global file starsCat
            
            nStars=str2double(app.nDropDown.Value);
            R = app.RSlider.Value;
            alfa = app.AlfaSlider.Value;
            
            if file~=0
                
                try
                    
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        if El>0
                            count=count+1;
                            r = R*((90-El)/90);
                            iP=m/2-r*cosd(Az+alfa);
                            jP=n/2-r*sind(Az+alfa);
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            plot(app.UIAxes_2,jP,iP,'*');
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                    app.MarcarestrellasButton.Enable='on';
                catch err
                    msgbox(err.message);
                end
            end
            
        end

        % Value changed function: nDropDown
        function nDropDownValueChanged(app, event)
            global file starsCat
            
            nStars=str2double(app.nDropDown.Value);
            R=app.RSlider.Value;
            alfa=app.AlfaSlider.Value;
            
            if file~=0
                
                try
                    
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    starsCat=zeros(nStars,7); %... Az El iS jS
                    m=480;
                    n=640;
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    
                    while count2<=300 && count<nStars
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        if El>0
                            count=count+1;
                            r = R*((90-El)/90);
                            iP=m/2-r*cosd(Az+alfa);
                            jP=n/2-r*sind(Az+alfa);
                            starsCat(count,1)=RA(count2);
                            starsCat(count,2)=DEC(count2);
                            starsCat(count,3)=mV(count2);
                            starsCat(count,4)=Az;
                            starsCat(count,5)=El;
                            starsCat(count,6)=iP;
                            starsCat(count,7)=jP;
                            plot(app.UIAxes_2,jP,iP,'*');
                            hold(app.UIAxes_2,'on');
                        end
                        count2=count2+1;
                    end
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Callback function
        function IntegrarvideoButtonPushed(app, event)
            
        end

        % Button pushed function: IdentificarButton
        function IdentificarButtonPushed(app, event)
            global file promediada matrizFotom
            if file~=0
                
                try
                    fecha = getDate(file);
                    n=3/24+datenum(fecha,'yyyy-mm-dd-HH-MM-SS'); %to UTC
                    fecha=datestr(n,'yyyy/mm/dd HH:MM:SS');
                    stationId = getStationID(file);
                    fid = fopen('estaciones.txt');
                    C = textscan(fid,'%f%s%f%f');
                    fclose(fid);
                    
                    
                    LAT=C{3}(stationId);
                    LON=C{4}(stationId);
                    nStars=str2double(app.nDropDown.Value);
                    
                    p=load(strcat('Station ',num2str(stationId),'/constantesPlaca.txt'));
                    [~,~,RA,DEC,mV] = readStars('Estrellas.txt');
                    
                    hold(app.UIAxes_2,'off');
                    axis(app.UIAxes_2,'ij');
                    count=0;
                    count2=1;
                    matrizFotom=zeros(nStars,4);
                    figure;
                    imagesc(promediada);axis ij;
                    hold on
                    w=5;
                    while count2<=300 && count<nStars
                        
                        [Az,El] = RADec2AzEl(RA(count2),DEC(count2),fecha,LAT,LON,0);
                        
                        if El>5 && ~isnan(mV(count2))
                            
                            [I,J] = hor2ijCaldasTancredi(pi/2-deg2rad(El),deg2rad(Az),p);
                            if promediada(I,J)~=0
                                
                                %Outside mask
                                cuadrado=promediada(I-w:I+w,J-w:J+w);
                                [~,M]=max(cuadrado(:));
                                [ci,cj]=ind2sub(size(cuadrado),M);
                                count=count+1;
                                plot(round(cj+J-w-1),round(ci+I-w-1),'*');
                                matrizFotom(count,:)=[round(ci+I-w-1) round(cj+J-w-1) mV(count2) 90-El];
                                
                            end
                            
                        end
                        
                        count2=count2+1;
                    end
                    matrizFotom(matrizFotom(:,1)==0,:)=[];
                    app.CalibrarfotomButton.Enable='on';
                    
                catch err
                    msgbox(err.message);
                end
            else
                msgbox('Debe abrir imagen o video para integrar');
            end
        end

        % Button pushed function: CalibrarfotomButton
        function CalibrarfotomButtonPushed(app, event)
            global matrizFotom promediada P maeAjuste
            
            rBack=str2double(app.RbackgroundDropDown.Value);
            rStar=str2double(app.RstarDropDown.Value);
            try
                [P,matrizFotom2]=fotometria(matrizFotom,promediada,rBack,rStar);
                
                %% Plot vignetting model
                [m,n]=size(promediada);
                ii=repmat((1:m)',[1,n]);
                jj=repmat((1:n),[m,1]);
                r = sqrt((ii-m/2).^2+(jj-n/2).^2);
                vign = cos(P(1)*r*(pi/180)).^4;
                figure; contourf(vign); axis ij; colormap('jet');
                
                %% Plot mV+2.5*log(Flux) vs airmass
                figure;
                r = matrizFotom2(:,3);
                x = matrizFotom2(:,4);
                FlujoCorregidoPorVignetting = matrizFotom2(:,2)./(cos(P(1)*r*(pi/180)).^4);
                y=matrizFotom2(:,1)+2.5*log10(FlujoCorregidoPorVignetting);
                plot(matrizFotom2(:,4),y,'*');
                hold on
                plot(matrizFotom2(:,4),P(2)*x+P(3));
                xlabel('Airmass');ylabel('mV+2.5*log10(Flux)');
                legend('Observed','Model');
                
                %% Plot calculated vs real mV
                figure;
                mVcalc=getmV(matrizFotom2,P);
                mVreal=matrizFotom2(:,1);
                maeAjuste=mean(abs(mVcalc-mVreal)); %Mean Absolute Error of calibration
                errorbar(mVreal,mVcalc,ones(size(mVcalc))*maeAjuste,'*');
                hold on
                plot(mVreal,mVreal);
                xlabel('mV catalogue');ylabel('mV calculated');
                
                app.p_3Label.Text=strcat('p_3: ',num2str(P(1)));
                app.p_2Label.Text=strcat('p_2: ',num2str(P(2)));
                app.p_1Label.Text=strcat('p_1: ',num2str(P(3)));
                
                app.NestrellasLabel.Text=strcat('N estrellas: ',num2str(size(matrizFotom2,1)));
                app.MeanOCLabel_2.Text=strcat('Mean O-C: ',num2str(mean(abs(mVreal-mVcalc))));
            catch err
                msgbox(err.message);
            end
        end

        % Button pushed function: CentroideButton
        function CentroideButtonPushed(app, event)
            global vidObj file maximo
            
            if file~=0
                
                % Read video metadata
                duracion=vidObj.Duration;
                framerate=vidObj.FrameRate;
                nFrames=floor(framerate*duracion);
                
                % Set & inititalize params
                f0=app.FramesSlider.Value;
                lado=app.LcuadradopixEditField.Value;
                lado2=round(lado/2);
                maximo=zeros(nFrames-f0+1,2);
                % Select centroid in first frame
                vidObj.CurrentTime=(f0-1)/vidObj.FrameRate;
                vidFrame = rgb2gray(readFrame(vidObj));
                [m,n]=size(vidFrame);
                
                figure;
                imagesc(vidFrame);
                [jc0,ic0]=ginput(1);
                ic0=round(ic0);
                jc0=round(jc0);
                
                vidObj.CurrentTime=(f0-1)/vidObj.FrameRate;
                count=1;
                
                while vidObj.hasFrame                    
                    vidFrame = rgb2gray(readFrame(vidObj));
                    hold off;
                    imagesc(vidFrame);
                    cuadrado=vidFrame(max(1,ic0-lado2):min(m,ic0+lado2),max(1,jc0-lado2):min(n,jc0+lado2));
                    [iC,jC] = centroide(cuadrado);
                    
                    ic0=(iC+ic0-lado2-1);
                    jc0=(jC+jc0-lado2-1);
                    
                    maximo(count,1)=ic0;
                    maximo(count,2)=jc0;
                    
                    % Plot
                    hold on
                    plot(maximo(count,2),maximo(count,1),'*k');
                    rectangle('Position',[max(maximo(count,2)-lado2,1) max(maximo(count,1)-lado2,1) 2*lado2 2*lado2]);
                    pause(0.1);
                    count=count+1;
                end
                maximo(maximo(:,1)==0,:)=[];
                app.GaussianaButton.Enable='on';
                msgbox('Listo!');
            end
        end

        % Callback function
        function MedianaframesButtonPushed(app, event)
            
        end

        % Button pushed function: GaussianaButton
        function GaussianaButtonPushed(app, event)
            global maximo vidObj file centro Flujo tiempo
            
            if file~=0
                % Read video metadata
                duracion=vidObj.Duration;
                framerate=vidObj.FrameRate;
                nFrames=round(framerate*duracion);
                
                % Get first frame where fireball appears
                f0=round(app.FramesSlider.Value);
                
                % Initialize relevant vars
                centro = zeros(size(maximo));
                Flujo = zeros(size(maximo,1),1);
                noiseFloor = zeros(size(maximo,1),1);
                lado=app.LcuadradopixEditField.Value;
                lado2=round(lado/2);
                if isempty(tiempo)
                    tiempo=1:size(maximo,1);
                else
                    if tiempo(1)~=1
                        tiempo=tiempo(f0:end);
                    end
                end
                vidObj.CurrentTime=(f0-1)/vidObj.FrameRate;
                count=1;
                figure;
                for i=f0:nFrames
                    vidFrame = rgb2gray(readFrame(vidObj));
                    [m,n]=size(vidFrame);
                    ic0=maximo(count,1);
                    jc0=maximo(count,2);
                    cuadrado=double(vidFrame(max(1,ic0-lado2):min(m,ic0+lado2),max(1,jc0-lado2):min(n,jc0+lado2)));
                    I=max(1,ic0-lado2):min(m,ic0+lado2);
                    J=max(1,jc0-lado2):min(n,jc0+lado2);
                    [ii,jj]=meshgrid(I,J);
                    
                    %Find noisefloor
                    noiseFloor(count)=mode(cuadrado(:));
                    
                    if count>1 && noiseFloor(count)>mean(noiseFloor(1:count-1))+3*std(noiseFloor(1:count-1))
                        noiseFloor(count)=mean(noiseFloor(1:count-1));
                    end
                    
                    Z=(cuadrado-noiseFloor(count))./255;
                    XYZ=[ii(:) jj(:) Z(:)];
                    f = @(x)miGaussianaRotada(x,XYZ);
                    x0=[0 ic0 jc0 0.5 0.5];
                    salida = lsqnonlin(f,x0);
                    Zsalida = dameGaussianaRotada(salida,ii,jj).*255;
                    Flujo(count) = integrarFlujoBolido(Zsalida);
                    centro(count,1)=salida(2);
                    centro(count,2)=salida(3);
                    pico=max(max(Zsalida));
                    % Plot
                    if app.ContourButton.Value
                        hold off
                        imagesc(cuadrado-noiseFloor(count));
                        axis ij
                        hold on
                        contour(Zsalida,[pico*0.9 pico*0.5 pico*0.05],'Linewidth',3,'Linecolor','k');
                        colormap('jet');
                        pause(0.2);
                    else
                        hold off
                        surf(cuadrado-noiseFloor(count));
                        axis([1 2*lado2+1 1 2*lado2+1 0 255]);
                        pause(0.2);
                        hold off
                        surf(Zsalida);
                        axis([1 2*lado2+1 1 2*lado2+1 0 255]);
                        pause(0.2);
                    end
                    count=count+1;
                    app.AstromyfotomButton.Enable='on';
                end
                msgbox('Listo!');
                
            end
        end

        % Button pushed function: AstromyfotomButton
        function AstromyfotomButtonPushed(app, event)
            global vidObj file centro Flujo P tiempo maeAjuste
            
            if file~=0
                if ~isempty(P)
                    %Get UTC, and Convert to string
                    utc=(app.UTEditField.Value);
                    if utc>0
                        utc=strcat('+',num2str(utc));
                    else
                        utc=num2str(utc);
                    end
                    %Get station id
                    id=str2double(file(9:10));
                    if isnan(id)
                        id=str2double(file(9:9));
                    end
                    %Get mean O-C of photometric calibration
                    
                    vidObj.CurrentTime=0/vidObj.FrameRate;
                    vidFrame = rgb2gray(readFrame(vidObj));
                    [m,n]=size(vidFrame);
                    z = zeros(size(Flujo));
                    Az = zeros(size(Flujo));
                    mV = zeros(size(Flujo));
                    thr=app.UmbralmagnitudEditField.Value;
                    corte=length(mV);
                    for i=1:length(Flujo)
                        [z(i),Az(i),mV(i)] = astromYfotomBolido(centro(i,:),m,n,P,Flujo(i),id);
                        if mV(i)>=thr
                            corte=i-1;
                            break
                        end
                    end
                    
                    % Limpiar vectores
                    mV2=mV(1:min(corte,length(tiempo)));
                    z2=z(1:min(corte,length(tiempo)));
                    Az2=Az(1:min(corte,length(tiempo)));
                    Flujo2=Flujo(1:min(corte,length(tiempo)));
                    tiempo2=tiempo(1:min(corte,length(tiempo)));
                    
                    %Graficar resultados
                    figure;
                    polarplot(Az2,rad2deg(z2),'*');
                    axis([0 360 0 90])
                    title('Fireball trajectory');
                    
                    figure;
                    plot(tiempo2,rad2deg(Az2),'LineWidth',4);
                    xlabel(strcat('Local Time [UT',utc,']'));
                    ylabel('Az [deg]');
                    datetick('x','HH:MM:SS');
                    
                    figure;
                    plot(tiempo2,rad2deg(z2),'LineWidth',4);
                    xlabel(strcat('Local Time [UT',utc,']'));
                    ylabel('z [deg]');
                    datetick('x','HH:MM:SS')
                    
                    figure;                    
                    errorbar(tiempo2,real(mV2),ones(size(tiempo2))*maeAjuste);
                    xlabel(strcat('Local Time [UT',utc,']'));
                    ylabel('mV');
                    set(gca, 'YDir','reverse')
                    datetick('x','HH:MM:SS')
                    
                    figure;
                    plot(tiempo2,Flujo2);
                    xlabel(strcat('Local Time [UT',utc,']'));
                    ylabel('Integrated flux [ADUs]');
                    datetick('x','HH:MM:SS')
                end
                msgbox('Listo!');
            end
        end

        % Button pushed function: AbrirmetadatosButton
        function AbrirmetadatosButtonPushed(app, event)
            global tiempo
            [file,path]=uigetfile('*.mat','Abrir archivo de metadatos...');
            if file~=0
                utc=(app.UTEditField.Value);
                try
                    A = load(strcat(path,file));
                    tiempo = zeros(length(A.metadata),1);
                    tGPS0 = A.t(1)+utc/24; %t00 time in bolidosGUI, in Local Time.
                    tFr0 = datenum(A.metadata(1).AbsTime); %timestamp of first frame. CPU (local) time.
                    
                    % Calculate offset between GPS and CPU time
                    delta = tGPS0-tFr0; 
                    if abs(delta)<(1/(60*60*24)) %less than 1 sec, since 1 sec is max time of getGPS.
                        delta=0;
                    end
                    
                    %Adjust all (CPU) timestamps contained in metadata, according to calculated offset
                    for i=1:length(A.metadata)
                        tiempo(i)=datenum(A.metadata(i).AbsTime)+delta;
                    end
                catch err
                    msgbox(err.message);
                end
            end
        end

        % Callback function
        function AbrirmascaraButtonPushed(app, event)
            
        end

        % Button pushed function: EvaluarAlgoritmoDetButton
        function EvaluarAlgoritmoDetButtonPushed(app, event)
            global vidObj file
            try
                stationId = getStationID(file);
                try
                    BW=load(strcat('Station ',num2str(stationId),'/Mask.mat'));
                catch err
                    BW=load('Mask.mat');
                end
                [m,n]=size(BW.BW);
                delayT=app.DelayEditField.Value;
                thr=app.UmbraldiferenciaEditField.Value;
                nThr=app.pixsobreumbraldifEditField.Value;
                thrR=app.MaxdistmedianaacentroideEditField.Value;
                
                [framesArray,nDif,rm,indiceC] = evalDetAlg(vidObj,BW.BW,delayT,thr,nThr,thrR);
                figure();
                plot((0:length(nDif)-1)./vidObj.FrameRate,nDif);xlabel('Time [s]');ylabel('Number of pixels above threshold');
                figure();
                plot((0:length(nDif)-1)./vidObj.FrameRate,rm);xlabel('Time [s]');ylabel('Median distance to centroid');
                figure();
                plot((0:length(nDif)-1)./vidObj.FrameRate,framesArray);xlabel('Time [s]');ylabel('Detection condition');title(strcat('Delay [s]:',num2str(delayT),';','Thr dif:',num2str(thr),';','Thr R:',num2str(thrR)));
                figure;
                plot(indiceC(:,2),indiceC(:,1),'*');xlabel('j');ylabel('i');title('Centroid coordinates');axis([1 n 1 m]);axis ij;
            catch err
                msgbox(err.message);
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            id=app.IDEditField.Value;
            alias=app.AliasEditField.Value;
            lat=app.LATEditField.Value;
            lon=app.LONEditField.Value;
            if abs(lat)<=90
                if abs(lon)<180
                    [~,MESSAGE,~] = mkdir(strcat('Station',num2str(id)));
                    if isempty(MESSAGE)
                        fid = fopen('estaciones.txt','a+');
                        fprintf(fid,'%.0f %s %6.6f %6.6f\n',id,alias,lat,lon);
                        fclose(fid);
                        msgbox('Estación creada con éxito!');
                    else
                        msgbox('La estación ya existe!');
                    end
                end
            end
        end

        % Button pushed function: VermscaraButton
        function VermscaraButtonPushed(app, event)
            global file vidObj
            v=app.FramesSlider.Value;
            vidObj.CurrentTime=((v-1)/vidObj.FrameRate);
            vidFrame = readFrame(vidObj);
            vidFrame=vidFrame(:,:,1);
            [m,n]=size(vidFrame);
            mascara=ones(m,n);
            if strcmp(app.VermscaraButton.Text,'Ver máscara')
                stationId=getStationID(file);                
                try
                    BW=load(strcat('Station ',num2str(stationId),'/Mask.mat'));
                catch err
                    BW=load('Mask.mat');
                end
                mascara=BW.BW;
                app.VermscaraButton.Text='Quitar máscara';
            else                
                app.VermscaraButton.Text='Ver máscara';
            end
            vidFrame=uint8(mascara).*vidFrame;
            imagesc(app.UIAxes,vidFrame);
            grid(app.UIAxes,'on');
            
        end

        % Callback function
        function DefinirmascaraButtonPushed(app, event)
            global file vidObj
            stationId=getStationID(file);
            v=app.FramesSlider.Value;
            vidObj.CurrentTime=((v-1)/vidObj.FrameRate);
            vidFrame = readFrame(vidObj);
            vidFrame=vidFrame(:,:,1);
            figure;
            imshow(vidFrame);
            waitfor(msgbox('Defina máscara haciendo click en sus vértices. Finalice con doble click. Espacio, ESC o DEL para cancelar.'));
            [~,BW]=roifill(vidFrame);
            img2=vidFrame.*uint8(BW);
            imshow(img2);
            save(strcat('Station',num2str(stationId),'/Mask.mat'),'BW');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagen cielo (color falso)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [1 640];
            app.UIAxes.YLim = [1 480];
            app.AbrirvideoButton = uibutton(app.UIFigure, 'push');
            app.AbrirvideoButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirvideoButtonPushed, true);
            app.AbrirvideoButton.Text = 'Abrir video';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 1800];
            app.FramesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];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
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.FramesSlider.Enable = 'off';
            app.FramesSlider.Value = 1;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Mapa sintético de estrellas')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [1 640];
            app.UIAxes_2.YLim = [0 480];
            app.IntegracindeVideoPanel = uipanel(app.UIFigure);
            app.IntegracindeVideoPanel.Title = 'Integración de Video';
            app.PromediarvideoButton = uibutton(app.IntegracindeVideoPanel, 'push');
            app.PromediarvideoButton.ButtonPushedFcn = createCallbackFcn(app, @PromediarvideoButtonPushed, true);
            app.PromediarvideoButton.Enable = 'off';
            app.PromediarvideoButton.Text = 'Promediar video';
            app.FrameinicialDropDownLabel = uilabel(app.IntegracindeVideoPanel);
            app.FrameinicialDropDownLabel.Text = 'Frame inicial';
            app.FrameinicialDropDown = uidropdown(app.IntegracindeVideoPanel);
            app.FrameinicialDropDown.Items = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4'};
            app.FrameinicialDropDown.Value = 'Frame 1';
            app.FramefinalDropDownLabel = uilabel(app.IntegracindeVideoPanel);
            app.FramefinalDropDownLabel.Text = 'Frame final';
            app.FramefinalDropDown = uidropdown(app.IntegracindeVideoPanel);
            app.FramefinalDropDown.Items = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4'};
            app.FramefinalDropDown.Value = 'Frame 1';
            app.StatusLampLabel = uilabel(app.IntegracindeVideoPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.IntegracindeVideoPanel);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.MincolorlevelSliderLabel = uilabel(app.UIFigure);
            app.MincolorlevelSliderLabel.Enable = 'off';
            app.MincolorlevelSliderLabel.Text = 'Min color level';
            app.MincolorlevelSlider = uislider(app.UIFigure);
            app.MincolorlevelSlider.ValueChangedFcn = createCallbackFcn(app, @MincolorlevelSliderValueChanged, true);
            app.MincolorlevelSlider.ValueChangingFcn = createCallbackFcn(app, @MincolorlevelSliderValueChanging, true);
            app.MincolorlevelSlider.Enable = 'off';
            app.MaxcolorlevelSliderLabel = uilabel(app.UIFigure);
            app.MaxcolorlevelSliderLabel.Enable = 'off';
            app.MaxcolorlevelSliderLabel.Text = 'Max color level';
            app.MaxcolorlevelSlider = uislider(app.UIFigure);
            app.MaxcolorlevelSlider.ValueChangedFcn = createCallbackFcn(app, @MaxcolorlevelSliderValueChanged, true);
            app.MaxcolorlevelSlider.ValueChangingFcn = createCallbackFcn(app, @MaxcolorlevelSliderValueChanging, true);
            app.MaxcolorlevelSlider.Enable = 'off';
            app.FiltrarimagenPanel = uipanel(app.UIFigure);
            app.FiltrarimagenPanel.Title = 'Filtrar imagen';
            app.FiltrarButton = uibutton(app.FiltrarimagenPanel, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Enable = 'off';
            app.FiltrarButton.Text = 'Filtrar';
            app.RtophatDropDownLabel = uilabel(app.FiltrarimagenPanel);
            app.RtophatDropDownLabel.Text = 'R tophat';
            app.RtophatDropDown = uidropdown(app.FiltrarimagenPanel);
            app.RtophatDropDown.Items = {'2', '4', '6', '8', '10', '12'};
            app.RtophatDropDown.Value = '2';
            app.MapadeestrellasPanel = uipanel(app.UIFigure);
            app.MapadeestrellasPanel.Title = 'Mapa de estrellas';
            app.GenerarButton = uibutton(app.MapadeestrellasPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Enable = 'off';
            app.GenerarButton.Text = 'Generar';
            app.AlfaSliderLabel = uilabel(app.MapadeestrellasPanel);
            app.AlfaSliderLabel.Text = 'Alfa';
            app.AlfaSlider = uislider(app.MapadeestrellasPanel);
            app.AlfaSlider.Limits = [0 360];
            app.AlfaSlider.MajorTicks = [];
            app.AlfaSlider.MajorTickLabels = {};
            app.AlfaSlider.ValueChangedFcn = createCallbackFcn(app, @AlfaSliderValueChanged, true);
            app.AlfaSlider.ValueChangingFcn = createCallbackFcn(app, @AlfaSliderValueChanging, true);
            app.AlfaSlider.MinorTicks = [];
            app.RSliderLabel = uilabel(app.MapadeestrellasPanel);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.MapadeestrellasPanel);
            app.RSlider.Limits = [0 280];
            app.RSlider.MajorTicks = [];
            app.RSlider.MajorTickLabels = {};
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.MinorTicks = [];
            app.nDropDownLabel = uilabel(app.MapadeestrellasPanel);
            app.nDropDownLabel.Text = 'n*';
            app.nDropDown = uidropdown(app.MapadeestrellasPanel);
            app.nDropDown.Items = {'20', '50', '100', '300', '10'};
            app.nDropDown.ValueChangedFcn = createCallbackFcn(app, @nDropDownValueChanged, true);
            app.nDropDown.Value = '10';
            app.IdentificarButton = uibutton(app.MapadeestrellasPanel, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificarButtonPushed, true);
            app.IdentificarButton.Enable = 'off';
            app.IdentificarButton.Text = 'Identificar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AstrometraTab = uitab(app.TabGroup);
            app.AstrometraTab.Title = 'Astrometría';
            app.MarcarestrellasButton = uibutton(app.AstrometraTab, 'push');
            app.MarcarestrellasButton.ButtonPushedFcn = createCallbackFcn(app, @MarcarestrellasButtonPushed, true);
            app.MarcarestrellasButton.Enable = 'off';
            app.MarcarestrellasButton.Text = 'Marcar estrellas';
            app.CalibrarButton = uibutton(app.AstrometraTab, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Enable = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            app.VerCalibracionButton = uibutton(app.AstrometraTab, 'push');
            app.VerCalibracionButton.ButtonPushedFcn = createCallbackFcn(app, @VerCalibracionButtonPushed, true);
            app.VerCalibracionButton.Enable = 'off';
            app.VerCalibracionButton.Text = 'Ver Calibracion';
            app.ResultadosPanel = uipanel(app.AstrometraTab);
            app.ResultadosPanel.Title = 'Resultados';
            app.MeanOCLabel = uilabel(app.ResultadosPanel);
            app.MeanOCLabel.Text = 'Mean O-C';
            app.ResolLabel = uilabel(app.ResultadosPanel);
            app.ResolLabel.Text = 'Resol.';
            app.estrellasLabel = uilabel(app.ResultadosPanel);
            app.estrellasLabel.Text = '# estrellas';
            app.MeanOCzLabel = uilabel(app.ResultadosPanel);
            app.MeanOCzLabel.Text = 'Mean O-C,z:';
            app.MeanOCAzLabel = uilabel(app.ResultadosPanel);
            app.MeanOCAzLabel.Text = 'Mean O-C, Az:';
            app.FotometraTab = uitab(app.TabGroup);
            app.FotometraTab.Title = 'Fotometría';
            app.ResultadosPanel_2 = uipanel(app.FotometraTab);
            app.ResultadosPanel_2.Title = 'Resultados';
            app.kLabel = uilabel(app.ResultadosPanel_2);
            app.kLabel.Text = 'k:';
            app.CLabel = uilabel(app.ResultadosPanel_2);
            app.CLabel.Text = 'C:';
            app.NestrellasLabel = uilabel(app.ResultadosPanel_2);
            app.NestrellasLabel.Text = 'N estrellas:';
            app.MeanOCLabel_2 = uilabel(app.ResultadosPanel_2);
            app.MeanOCLabel_2.Text = 'Mean O-C:';
            app.p_3Label = uilabel(app.ResultadosPanel_2);
            app.p_3Label.Text = 'p_3:';
            app.p_2Label = uilabel(app.ResultadosPanel_2);
            app.p_2Label.Text = 'p_2:';
            app.p_1Label = uilabel(app.ResultadosPanel_2);
            app.p_1Label.Text = 'p_1:';
            app.RbackgroundDropDownLabel = uilabel(app.FotometraTab);
            app.RbackgroundDropDownLabel.Text = 'R background';
            app.RbackgroundDropDown = uidropdown(app.FotometraTab);
            app.RbackgroundDropDown.Items = {'2', '3', '4', '5', '6', '10'};
            app.RbackgroundDropDown.Value = '2';
            app.RstarDropDownLabel = uilabel(app.FotometraTab);
            app.RstarDropDownLabel.Text = 'R star';
            app.RstarDropDown = uidropdown(app.FotometraTab);
            app.RstarDropDown.Items = {'1', '2', '3', '4'};
            app.RstarDropDown.Value = '1';
            app.CalibrarfotomButton = uibutton(app.FotometraTab, 'push');
            app.CalibrarfotomButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarfotomButtonPushed, true);
            app.CalibrarfotomButton.Enable = 'off';
            app.CalibrarfotomButton.Text = 'Calibrar fotom.';
            app.BlidoTab = uitab(app.TabGroup);
            app.BlidoTab.Title = 'Bólido';
            app.CentroideButton = uibutton(app.BlidoTab, 'push');
            app.CentroideButton.ButtonPushedFcn = createCallbackFcn(app, @CentroideButtonPushed, true);
            app.CentroideButton.Enable = 'off';
            app.CentroideButton.Text = 'Centroide';
            app.GaussianaButton = uibutton(app.BlidoTab, 'push');
            app.GaussianaButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianaButtonPushed, true);
            app.GaussianaButton.Enable = 'off';
            app.GaussianaButton.Text = 'Gaussiana';
            app.AstromyfotomButton = uibutton(app.BlidoTab, 'push');
            app.AstromyfotomButton.ButtonPushedFcn = createCallbackFcn(app, @AstromyfotomButtonPushed, true);
            app.AstromyfotomButton.Enable = 'off';
            app.AstromyfotomButton.Text = 'Astrom. y fotom.';
            app.LcuadradopixEditFieldLabel = uilabel(app.BlidoTab);
            app.LcuadradopixEditFieldLabel.Text = 'L cuadrado (pix)';
            app.LcuadradopixEditField = uieditfield(app.BlidoTab, 'numeric');
            app.LcuadradopixEditField.Limits = [2 240];
            app.LcuadradopixEditField.Value = 40;
            app.VisualizarGaussianaButtonGroup = uibuttongroup(app.BlidoTab);
            app.VisualizarGaussianaButtonGroup.Title = 'Visualizar Gaussiana';
            app.ContourButton = uiradiobutton(app.VisualizarGaussianaButtonGroup);
            app.ContourButton.Text = 'Contour';
            app.ContourButton.Value = true;
            app.SurfButton = uiradiobutton(app.VisualizarGaussianaButtonGroup);
            app.SurfButton.Text = 'Surf';
            app.UmbralmagnitudEditFieldLabel = uilabel(app.BlidoTab);
            app.UmbralmagnitudEditFieldLabel.Text = 'Umbral magnitud';
            app.UmbralmagnitudEditField = uieditfield(app.BlidoTab, 'numeric');
            app.DeteccinTab = uitab(app.TabGroup);
            app.DeteccinTab.Title = 'Detección';
            app.EvaluarAlgoritmoDetButton = uibutton(app.DeteccinTab, 'push');
            app.EvaluarAlgoritmoDetButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluarAlgoritmoDetButtonPushed, true);
            app.EvaluarAlgoritmoDetButton.Enable = 'off';
            app.EvaluarAlgoritmoDetButton.Text = 'Evaluar Algoritmo Det.';
            app.DelayEditFieldLabel = uilabel(app.DeteccinTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.DeteccinTab, 'numeric');
            app.DelayEditField.Value = 0.1;
            app.UmbraldiferenciaEditFieldLabel = uilabel(app.DeteccinTab);
            app.UmbraldiferenciaEditFieldLabel.Text = 'Umbral diferencia';
            app.UmbraldiferenciaEditField = uieditfield(app.DeteccinTab, 'numeric');
            app.UmbraldiferenciaEditField.Value = 25;
            app.pixsobreumbraldifEditFieldLabel = uilabel(app.DeteccinTab);
            app.pixsobreumbraldifEditFieldLabel.Text = '# pix sobre umbral dif';
            app.pixsobreumbraldifEditField = uieditfield(app.DeteccinTab, 'numeric');
            app.pixsobreumbraldifEditField.Value = 10;
            app.MaxdistmedianaacentroideEditFieldLabel = uilabel(app.DeteccinTab);
            app.MaxdistmedianaacentroideEditFieldLabel.Text = 'Max dist mediana a centroide';
            app.MaxdistmedianaacentroideEditField = uieditfield(app.DeteccinTab, 'numeric');
            app.MaxdistmedianaacentroideEditField.Value = 30;
            app.AbrirmetadatosButton = uibutton(app.UIFigure, 'push');
            app.AbrirmetadatosButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirmetadatosButtonPushed, true);
            app.AbrirmetadatosButton.Text = 'Abrir metadatos';
            app.CrearestacinPanel = uipanel(app.UIFigure);
            app.CrearestacinPanel.Title = 'Crear estación...';
            app.LATEditFieldLabel = uilabel(app.CrearestacinPanel);
            app.LATEditFieldLabel.Text = 'LAT';
            app.LATEditField = uieditfield(app.CrearestacinPanel, 'numeric');
            app.LATEditField.Limits = [-90 90];
            app.LONEditFieldLabel = uilabel(app.CrearestacinPanel);
            app.LONEditFieldLabel.Text = 'LON';
            app.LONEditField = uieditfield(app.CrearestacinPanel, 'numeric');
            app.LONEditField.Limits = [-179 180];
            app.AliasEditFieldLabel = uilabel(app.CrearestacinPanel);
            app.AliasEditFieldLabel.Text = 'Alias';
            app.AliasEditField = uieditfield(app.CrearestacinPanel, 'text');
            app.AgregarButton = uibutton(app.CrearestacinPanel, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar...';
            app.IDEditFieldLabel = uilabel(app.CrearestacinPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.CrearestacinPanel, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.VermscaraButton = uibutton(app.UIFigure, 'push');
            app.VermscaraButton.ButtonPushedFcn = createCallbackFcn(app, @VermscaraButtonPushed, true);
            app.VermscaraButton.Enable = 'off';
            app.VermscaraButton.Text = 'Ver máscara';
            app.UTEditFieldLabel = uilabel(app.UIFigure);
            app.UTEditFieldLabel.Text = 'UT';
            app.UTEditField = uieditfield(app.UIFigure, 'numeric');
            app.UTEditField.Value = -3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end